classdef mlTSKapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        DimensionalityReductionDropDownLabel  matlab.ui.control.Label
        DimensionalityReductionDropDown  matlab.ui.control.DropDown
        SubspcaeDimensionalityEditFieldLabel  matlab.ui.control.Label
        SubspcaeDimensionalityEditField  matlab.ui.control.NumericEditField
        ParameterInitializationDropDownLabel  matlab.ui.control.Label
        ParameterInitializationDropDown  matlab.ui.control.DropDown
        MembershipFunctionMFDropDownLabel  matlab.ui.control.Label
        MembershipFunctionMFDropDown    matlab.ui.control.DropDown
        NumberofMFspervariableEditFieldLabel  matlab.ui.control.Label
        NumberofMFspervariableEditField  matlab.ui.control.NumericEditField
        ParameterOptimizationDropDownLabel  matlab.ui.control.Label
        ParameterOptimizationDropDown   matlab.ui.control.DropDown
        PowerballpowerexponentSliderLabel  matlab.ui.control.Label
        PowerballpowerexponentSlider    matlab.ui.control.Slider
        DropRuleDropProbabilityLabel    matlab.ui.control.Label
        DropRulepreservationrateSlider  matlab.ui.control.Slider
        log10InitialLearningRateLabel   matlab.ui.control.Label
        log10InitiallearningrateSpinner  matlab.ui.control.Spinner
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        NumberofIterationsEditField     matlab.ui.control.NumericEditField
        BatchsizeEditFieldLabel         matlab.ui.control.Label
        BatchsizeEditField              matlab.ui.control.NumericEditField
        L2regularizationcoefficientSpinnerLabel  matlab.ui.control.Label
        L2regularizationcoefficientSpinner  matlab.ui.control.Spinner
        LoadDataandRunButton            matlab.ui.control.Button
        UncertainDropDownLabel          matlab.ui.control.Label
        UncertainDropDown               matlab.ui.control.DropDown
        TypeReductionDropDownLabel      matlab.ui.control.Label
        TypeReductionDropDown           matlab.ui.control.DropDown
        RulePruningPDropDown            matlab.ui.control.DropDown
        RulePruningDropDownLabel        matlab.ui.control.Label
        URSpinnerLabel                  matlab.ui.control.Label
        URSpinner                       matlab.ui.control.Spinner
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Result                          matlab.ui.control.UIAxes
        ValFL                           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataandRunButton
        function LoadDataandRunButtonPushed(app, event)
        
        % Display uigetfile dialog
        filterspec = {'*.mat','MAT Files'};
        [f, p] = uigetfile(filterspec);
        
        % Make sure user didn't cancel uigetfile dialog
        if (ischar(p))
            fname = [p f];
            load(fname,'-mat', 'trainX', 'tuneX', 'testX', 'trainY', 'tuneY', 'testY');
            isRegression = size(trainY,2)==1;
            if isRegression
                %% normalize y
                mY = mean(trainY);
                sY = std(trainY);
                trainY = (trainY - mY) / sY;
                tuneY = (tuneY - mY) / sY;
                testY = (testY - mY) / sY;
            end
            MF = app.MembershipFunctionMFDropDown.Value;
            DR = app.DimensionalityReductionDropDown.Value;
            nF = app.SubspcaeDimensionalityEditField.Value;
            Init = app.ParameterInitializationDropDown.Value;
            nMFs = app.NumberofMFspervariableEditField.Value;
            Uncertain = app.UncertainDropDown.Value;
            TR = app.TypeReductionDropDown.Value;
            RP = app.RulePruningPDropDown.Value;
            
            Opt = app.ParameterOptimizationDropDown.Value;
            Powerball = app.PowerballpowerexponentSlider.Value;
            DropRule = app.DropRulepreservationrateSlider.Value;
            lr = 10^(app.log10InitiallearningrateSpinner.Value);
            l2 = 10^(app.L2regularizationcoefficientSpinner.Value);
            UR = app.URSpinner.Value;
            nIt = app.NumberofIterationsEditField.Value;
            Nbs = app.BatchsizeEditField.Value;
            
            switch RP
                case {'None'}
                    RP=nan;
                case {'Vanilla'}
                    RP=nIt/2;
                    nMFs=nMFs*2;
                case {'Adaptive'}
                    RP=[0,nIt/2];
                    nMFs=nMFs*2;
            end
            
            rng(0)
            [tmp,tmpt,AntecedentsB, WB, WAB, WCB, fB]=mlTSK(trainX,trainY,{tuneX,testX},{tuneY,testY},'MF',MF,'DR',DR,'nF',nF,'Init',Init,'nMF',nMFs,'Uncertain',Uncertain,'TR',TR,'RP',RP,'Opt',Opt,'Powerball',Powerball,'DropRule',DropRule,'lr',lr,'l2',l2,'nIt',nIt,'Nbs',Nbs,'UR',UR);
            
            [Resulttrain,Resulttune,Resulttest]=deal(tmp,tmpt{1},tmpt{2});
            save('mat_results.mat','MF','DR','nF','Init','nMFs','Uncertain','TR','RP','Opt','Powerball','DropRule','lr','l2','nIt','Nbs','Resulttrain','Resulttune','Resulttest','AntecedentsB','WB','WAB','WCB','fB');
            fis=num2fis(AntecedentsB, WB, WAB);
            
            nPoints=nIt/20+1;
            idp=fix(linspace(1,nIt,nPoints));
            set(app.Result,'DefaulttextFontName','times new roman','DefaultaxesFontName','times new roman');
            plot(app.Result,idp,Resulttrain,'k',idp,Resulttune,'b',idp,Resulttest,'r','linewidth',2)
            hold(app.Result,"on")
            i0=1;
            if ~isnan(RP)
                i0 = find(idp>=RP(end),'first');
            end
            if isRegression
                [mResulttune,id]=min(Resulttune(i0:end));
            else
                [mResulttune,id]=max(Resulttune(i0:end));
            end
            id=i0+id-1;
            plot(app.Result, [idp(id) idp(id)],[0 mResulttune],'b--','linewidth',2);
            legend(app.Result,{'Train','Val.','Test','Best Val. Iter.'},'location','best','box','off');
            set(app.Result,'xlim',[1,nIt])
            if isRegression
                title(app.Result,[f(1:end-4) ' (testRMSE= ' num2str(Resulttest(id),'%.2f') ')']);
                ylabel(app.Result, 'Root Mean Squared Error')
            else
                title(app.Result,[f(1:end-4) ' (testBCA= ' num2str(Resulttest(id),'%.2f') ')']);
                ylabel(app.Result, 'Balanced Classification Accuracy')
            end
            hold(app.Result,"off")
            
            set(app.ValFL,'DefaulttextFontName','times new roman','DefaultaxesFontName','times new roman');
            fB=fB/sum(fB);
            plot(app.ValFL,1:length(fB),sort(fB,'descend'),'b','linewidth',2)
            set(app.ValFL,'xlim',[1,length(fB)])
            title(app.ValFL,[f(1:end-4) ' (maxFL/minFL= ' num2str(max(fB)/min(fB),'%5.2f') ')']);
            
        end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {628, 628};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {308, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.DimensionalityReductionDropDownLabel = uilabel(app.GridLayout2);
            app.DimensionalityReductionDropDownLabel.Text = 'Dimensionality Reduction';
            app.DimensionalityReductionDropDown = uidropdown(app.GridLayout2);
            app.DimensionalityReductionDropDown.Items = {'None', 'PCAfixed', 'DR', 'CDR', 'FS', 'CFS', 'BNA', 'BNC', 'CBN'};
            app.DimensionalityReductionDropDown.Value = 'CDR';
            app.SubspcaeDimensionalityEditFieldLabel = uilabel(app.GridLayout2);
            app.SubspcaeDimensionalityEditFieldLabel.Text = 'Subspcae Dimensionality';
            app.SubspcaeDimensionalityEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SubspcaeDimensionalityEditField.Value = 4;
            app.ParameterInitializationDropDownLabel = uilabel(app.GridLayout2);
            app.ParameterInitializationDropDownLabel.Text = 'Parameter Initialization';
            app.ParameterInitializationDropDown = uidropdown(app.GridLayout2);
            app.ParameterInitializationDropDown.Items = {'GP', 'FCMx', 'LogFCM', 'HFCM', 'LogHFCM', 'FCM-LN', 'FCM-LN-ReLU', 'FCMy', 'kMx', 'kMy'};
            app.ParameterInitializationDropDown.Value = 'FCMx';
            app.MembershipFunctionMFDropDownLabel = uilabel(app.GridLayout2);
            app.MembershipFunctionMFDropDownLabel.Text = 'Membership Function (MF)';
            app.MembershipFunctionMFDropDown = uidropdown(app.GridLayout2);
            app.MembershipFunctionMFDropDown.Items = {'Gaussian', 'Bell-Shaped', 'Trapezoidal', 'Triangular'};
            app.MembershipFunctionMFDropDown.Value = 'Gaussian';
            app.NumberofMFspervariableEditFieldLabel = uilabel(app.GridLayout2);
            app.NumberofMFspervariableEditFieldLabel.Text = 'Number of MFs per Variable';
            app.NumberofMFspervariableEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofMFspervariableEditField.Value = 16;
            app.ParameterOptimizationDropDownLabel = uilabel(app.GridLayout2);
            app.ParameterOptimizationDropDownLabel.Text = 'Parameter Optimization';
            app.ParameterOptimizationDropDown = uidropdown(app.GridLayout2);
            app.ParameterOptimizationDropDown.Items = {'AdaBound', 'SGDM', 'Adam', 'AdaBelief'};
            app.ParameterOptimizationDropDown.Value = 'AdaBelief';
            app.PowerballpowerexponentSliderLabel = uilabel(app.GridLayout2);
            app.PowerballpowerexponentSliderLabel.Text = 'Powerball Power Exponent';
            app.PowerballpowerexponentSlider = uislider(app.GridLayout2);
            app.PowerballpowerexponentSlider.Limits = [0 1];
            app.PowerballpowerexponentSlider.MajorTicks = [0 0.5 1];
            app.PowerballpowerexponentSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PowerballpowerexponentSlider.Value = 0.5;
            app.DropRuleDropProbabilityLabel = uilabel(app.GridLayout2);
            app.DropRuleDropProbabilityLabel.Text = 'DropRule Drop Probability ';
            app.DropRulepreservationrateSlider = uislider(app.GridLayout2);
            app.DropRulepreservationrateSlider.Limits = [0 1];
            app.DropRulepreservationrateSlider.MajorTicks = [0 0.5 1];
            app.DropRulepreservationrateSlider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.DropRulepreservationrateSlider.Value = 0.5;
            app.log10InitialLearningRateLabel = uilabel(app.GridLayout2);
            app.log10InitialLearningRateLabel.Text = 'log10( Initial Learning Rate )';
            app.log10InitiallearningrateSpinner = uispinner(app.GridLayout2);
            app.log10InitiallearningrateSpinner.Step = 0.5;
            app.log10InitiallearningrateSpinner.Value = -2;
            app.NumberofIterationsEditFieldLabel = uilabel(app.GridLayout2);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofIterationsEditField.Value = 1000;
            app.BatchsizeEditFieldLabel = uilabel(app.GridLayout2);
            app.BatchsizeEditFieldLabel.Text = 'Mini-Batch Size';
            app.BatchsizeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BatchsizeEditField.Value = 64;
            app.L2regularizationcoefficientSpinnerLabel = uilabel(app.GridLayout2);
            app.L2regularizationcoefficientSpinnerLabel.Text = 'log10( L2 Regularization )';
            app.L2regularizationcoefficientSpinner = uispinner(app.GridLayout2);
            app.L2regularizationcoefficientSpinner.Step = 0.5;
            app.L2regularizationcoefficientSpinner.Value = -4;
            app.LoadDataandRunButton = uibutton(app.GridLayout2, 'push');
            app.LoadDataandRunButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataandRunButtonPushed, true);
            app.LoadDataandRunButton.Text = 'Load Data and Run';
            app.UncertainDropDownLabel = uilabel(app.GridLayout2);
            app.UncertainDropDownLabel.Text = 'Type-2: Uncertain';
            app.UncertainDropDown = uidropdown(app.GridLayout2);
            app.UncertainDropDown.Items = {'None', 'Mean', 'Variance'};
            app.UncertainDropDown.Value = 'None';
            app.TypeReductionDropDownLabel = uilabel(app.GridLayout2);
            app.TypeReductionDropDownLabel.Text = 'Type-2: Type Reduction';
            app.TypeReductionDropDown = uidropdown(app.GridLayout2);
            app.TypeReductionDropDown.Items = {'Karnik-Mendel', 'Nie-Tan'};
            app.TypeReductionDropDown.Value = 'Karnik-Mendel';
            app.RulePruningPDropDown = uidropdown(app.GridLayout2);
            app.RulePruningPDropDown.Items = {'None', 'Vanilla', 'Adaptive'};
            app.RulePruningPDropDown.Value = 'None';
            app.RulePruningDropDownLabel = uilabel(app.GridLayout2);
            app.RulePruningDropDownLabel.Text = 'Rule Pruninig';
            app.URSpinnerLabel = uilabel(app.GridLayout2);
            app.URSpinnerLabel.Text = 'Uniform Regularization';
            app.URSpinner = uispinner(app.GridLayout2);
            app.URSpinner.Step = 0.5;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.Result = uiaxes(app.GridLayout3);
            title(app.Result, '')
            xlabel(app.Result, 'Iteration')
            ylabel(app.Result, '')
            app.ValFL = uiaxes(app.GridLayout3);
            title(app.ValFL, '')
            xlabel(app.ValFL, 'Sorted Rule Index')
            ylabel(app.ValFL, 'Normalized Val. Firing Level')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mlTSKapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end